classdef app_Tarefa3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        QuantidadedeBitsEditFieldLabel  matlab.ui.control.Label
        QuantidadedeBitsEditField       matlab.ui.control.NumericEditField
        INICIARButton                   matlab.ui.control.Button
        Grupo_TorneioEditFieldLabel     matlab.ui.control.Label
        Grupo_TorneioEditField          matlab.ui.control.NumericEditField
        EliteEditFieldLabel             matlab.ui.control.Label
        EliteEditField                  matlab.ui.control.NumericEditField
        SeleoButtonGroup                matlab.ui.container.ButtonGroup
        TorneioButton                   matlab.ui.control.RadioButton
        ElitismoButton                  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        Tarefa3AlgoritmosGenticosLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INICIARButton
        function INICIARButtonPushed(app, event)
            clc;
            close all;
            
%             Individuos = app.IndividuosEditField.Value;
            Bits = app.QuantidadedeBitsEditField.Value;
%             Torneios = app.TorneiosEditField.Value;
            Grupo_Torneio = app.Grupo_TorneioEditField.Value;
            Elite = app.EliteEditField.Value;
            
            Inicial_pop_X = randi([0 1], 50,Bits);
            Inicial_pop_Y = randi([0 1], 50,Bits);
            
%             Geracao_dec_X = zeros(1,Bits);
%             Geracao_dec_Y = zeros(1,Bits);
            
            
            % Primeiro passo: transformar cada linha binária em um numero real
            Geracao_bin_X = Inicial_pop_X;
            Geracao_bin_Y = Inicial_pop_Y;
            
            %entrar com matriz binaria e receber uma matriz dec
            Geracao_dec_X = bin_to_dec_X(Geracao_bin_X);
            Geracao_dec_Y = bin_to_dec_Y(Geracao_bin_Y);
            
            %% Avaliacao da população - cálculo de fitness
            
            % calcular o fitness de cada par X e Y gerado
            Fitness_XY = calcula_fitness_XY(Geracao_dec_X, Geracao_dec_Y);
            
            % calcular a média dos fitness dos indivíduos de cada geração por parzinho
            % X e Y
            Geracao_media_X = mean(Geracao_dec_X);
            Geracao_media_Y = mean(Geracao_dec_Y);
            Geracao_media(1,1) = mean(Fitness_XY);
            
            % Guardar a posicao do valor do PAR X e Y real que possuirem o maior valor para f(x)
            [Index_XY, Maximo_novo] = coleta_maximo_XY(Fitness_XY);
            
            % Selecionar o melhor PAR da Geracao
            x(1,1) = Geracao_dec_X(Index_XY,1);
            y(1,1) = Geracao_dec_Y(Index_XY,1);
            
            % Selecionar o melhor fitness (Melhor valor do PAR X e Y para máxima f(x))
            Maximo_valor(1,1) = Maximo_novo;

            if app.TorneioButton.Value   %where "Button1" is the name of the first radio button
                [Matriz_pais_X_selecionados, Matriz_pais_Y_selecionados] = torneio (Geracao_bin_X, Geracao_bin_Y, Fitness_XY, Grupo_Torneio);
                
            elseif app.ElitismoButton.Value
                [Matriz_pais_X_selecionados, Matriz_pais_Y_selecionados] = elitismo (Geracao_bin_X, Geracao_bin_Y, Fitness_XY, Elite);
            else
                Matriz_pais_X_selecionados = zeros(50,Bits);
                Matriz_pais_Y_selecionados = zeros(50,Bits);
            end
            
            %% Cross-Over -> After swapping all bits from a random position to the end
            [Matriz_saida_X] = crossover_app (Matriz_pais_X_selecionados,Bits);
            [Matriz_saida_Y] = crossover_app (Matriz_pais_Y_selecionados,Bits);
            
            % Mutação
            [Matriz_novageracao_X] = mutacao_app(Matriz_saida_X,Bits);
            [Matriz_novageracao_Y] = mutacao_app(Matriz_saida_Y,Bits);
            
            disp(Matriz_novageracao_X);
            disp(Matriz_novageracao_Y);
            
            
            %% Dentro do Looping - gerar novas matrizes que viram pais e assim por diante
            
            % o t varia da 2ª geracao até o numero de gerações desejada que neste caso
            % é 80
            for t = 2:80
                
                %%% Repete o Primeiro Processo de Avaliação dos Individuos
                
                %entrar com matriz binaria e receber uma matriz dec
                Geracao_dec_X = bin_to_dec_X(Geracao_bin_X);
                Geracao_dec_Y = bin_to_dec_Y(Geracao_bin_Y);
                
                % calcular o fitness de cada individuo
                Fitness_XY = calcula_fitness_XY(Geracao_dec_X, Geracao_dec_Y);
                
                % calcular a média dos fitness dos indivíduos de cada geração
                Geracao_media_X = mean(Geracao_dec_X);
                Geracao_media_Y = mean(Geracao_dec_Y);
                Geracao_media(t,1) = mean(Fitness_XY);
                
                % Guardar a posicao e o valor do x real que possui o minimo
                % valor para f(x)da geracao analisada
                [Index_XY, Maximo_novo] = coleta_maximo_XY(Fitness_XY);
                
                disp(Index_XY);
                
                % Selecionar o melhor PAR da Geracao
                x(t,1) = Geracao_dec_X(Index_XY,1);
                y(t,1) = Geracao_dec_Y(Index_XY,1);
                
                % Selecionar o melhor fitness (Melhor valor do PAR X e Y para máxima f(x))
                Maximo_valor(t,1) = Maximo_novo;
                
                
                if app.TorneioButton.Value   %where "Button1" is the name of the first radio button
                    [Matriz_pais_X_selecionados, Matriz_pais_Y_selecionados] = torneio (Geracao_bin_X, Geracao_bin_Y, Fitness_XY, Grupo_Torneio);
                    
                elseif app.ElitismoButton.Value
                    [Matriz_pais_X_selecionados, Matriz_pais_Y_selecionados] = elitismo (Geracao_bin_X, Geracao_bin_Y, Fitness_XY, Elite);
                else
                    Matriz_pais_X_selecionados = zeros(50,Bits);
                    Matriz_pais_Y_selecionados = zeros(50,Bits);
                end
                
                %% Cross-Over -> After swapping all bits from a random position to the end
                [Matriz_saida_X] = crossover_app (Matriz_pais_X_selecionados,Bits);
                [Matriz_saida_Y] = crossover_app (Matriz_pais_Y_selecionados, Bits);
                
                % Mutação
                [Matriz_novageracao_X] = mutacao_app(Matriz_saida_X,Bits);
                [Matriz_novageracao_Y] = mutacao_app(Matriz_saida_Y,Bits);
                
                %% Os filhos selecionados tornam-se pais da próxima geração
                Geracao_bin_X = Matriz_novageracao_X;
                Geracao_bin_Y = Matriz_novageracao_Y;
                
%                 disp(Matriz_novageracao_X);
%                 disp(Matriz_novageracao_Y);
%                 disp(Geracao_media);
%                 disp(x);
%                 disp(y);
                
                
            end

                plot(app.UIAxes,Maximo_valor,'s');
                xlabel('Geracoes');
                ylabel('F(x)');
                legend(app.UIAxes, 'Máximo Fitness');
                hold(app.UIAxes);
            

                plot(app.UIAxes2,x,'+');
                hold(app.UIAxes2);
                plot(app.UIAxes2,y,'*')
                legend(app.UIAxes2, 'Valor X', 'Valor Y');
                xlabel('Geracoes');
                ylabel('Valor');
                hold(app.UIAxes2);
            
                plot(app.UIAxes3,Geracao_media,'-');
                xlabel('Geracoes');
                ylabel('Média Fitness');
                hold(app.UIAxes3);
                
                Oi(:,1) = x;
                Oi(:,2) = y;
                Oi(:,3) = Geracao_media;
                disp(Oi);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.QuantidadedeBitsEditFieldLabel = uilabel(app.UIFigure);
            app.QuantidadedeBitsEditFieldLabel.Text = 'Quantidade de Bits';
            app.QuantidadedeBitsEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuantidadedeBitsEditField.Value = 10;
            app.INICIARButton = uibutton(app.UIFigure, 'push');
            app.INICIARButton.ButtonPushedFcn = createCallbackFcn(app, @INICIARButtonPushed, true);
            app.INICIARButton.Text = 'INICIAR';
            app.Grupo_TorneioEditFieldLabel = uilabel(app.UIFigure);
            app.Grupo_TorneioEditFieldLabel.Text = 'Grupo_Torneio';
            app.Grupo_TorneioEditField = uieditfield(app.UIFigure, 'numeric');
            app.Grupo_TorneioEditField.Value = 3;
            app.EliteEditFieldLabel = uilabel(app.UIFigure);
            app.EliteEditFieldLabel.Text = 'Elite';
            app.EliteEditField = uieditfield(app.UIFigure, 'numeric');
            app.EliteEditField.Value = 2;
            app.SeleoButtonGroup = uibuttongroup(app.UIFigure);
            app.SeleoButtonGroup.Title = 'Seleção';
            app.TorneioButton = uiradiobutton(app.SeleoButtonGroup);
            app.TorneioButton.Text = 'Torneio';
            app.TorneioButton.Value = true;
            app.ElitismoButton = uiradiobutton(app.SeleoButtonGroup);
            app.ElitismoButton.Text = 'Elitismo';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'F(x) x Gerações')
            xlabel(app.UIAxes, 'Gerações')
            ylabel(app.UIAxes, 'Best Fitness')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Valor X e Y x Gerações')
            xlabel(app.UIAxes2, 'Gerações')
            ylabel(app.UIAxes2, 'Valores')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Média Fitness x Gerações')
            xlabel(app.UIAxes3, 'Gerações')
            ylabel(app.UIAxes3, 'Média Fitness')
            app.Tarefa3AlgoritmosGenticosLabel = uilabel(app.UIFigure);
            app.Tarefa3AlgoritmosGenticosLabel.Text = 'Tarefa 3 - Algoritmos Genéticos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Tarefa3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end