classdef Tarefa4app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SCHEDULINGButton  matlab.ui.control.StateButton
        Tarefa04AlgoritmosGenticosLabel  matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SCHEDULINGButton
        function SCHEDULINGButtonValueChanged(app, event)
            value = app.SCHEDULINGButton.Value;
            Melhores_turnos = 0;
            Melhor_producao = 0;
            
            Geracao_inicial = randi([0 1], 4,7);
            
            Geracao_turnos_inicial = paradas(Geracao_inicial);
            
            Producao_inicial = producao(Geracao_turnos_inicial);
            
            [Producao_nova1, Geracao_nova1] = condicao2 (Producao_inicial, Geracao_turnos_inicial);
            
            %% Segunda scheduling para comparacao
            
            Geracao_inicial2 = randi([0 1], 4,7);
            
            Geracao_turnos_inicial2 = paradas(Geracao_inicial2);
            
            Producao_inicial2 = producao(Geracao_turnos_inicial2);
            
            [Producao_nova2, Geracao_nova2] = condicao2 (Producao_inicial2, Geracao_turnos_inicial2);
            
            %% Verificar qual scheduling ganha
            
            [Melhores_turnos, Melhor_producao] = compara(Producao_nova1, Producao_nova2, Geracao_nova1, Geracao_nova2);
            
            Geracao_nova1 = Melhores_turnos;
            Producao_nova1 = Melhor_producao;
            
            Prod_liq(1,:) = producao_liq_intervalos(Melhor_producao);
            Media_individuos(1,:) = media_ind(Melhores_turnos);
            
            %% REALIZAR O LOOPING
            
            for i = 2:100
                
                Geracao_inicial2 = randi([0 1], 4,7);
                
                Geracao_turnos_inicial2 = paradas(Geracao_inicial2);
                
                Producao_inicial2 = producao(Geracao_turnos_inicial2);
                
                [Producao_nova2, Geracao_nova2] = condicao2 (Producao_inicial2, Geracao_turnos_inicial2);
                
                %% Verificar qual scheduling ganha
                
                [Melhores_turnos, Melhor_producao] = compara(Producao_nova1, Producao_nova2, Geracao_nova1, Geracao_nova2);
                
                Geracao_nova1 = Melhores_turnos;
                Producao_nova1 = Melhor_producao;
                
                
                [Melhor_producao_FIM1, Melhores_turnos_FIM1] = condicao2(Melhor_producao, Melhores_turnos);
                [Melhor_producao_FIM2, Melhores_turnos_FIM2] = condicao2(Melhor_producao_FIM1, Melhores_turnos_FIM1);
                [Melhor_producao_FIM3, Melhores_turnos_FIM3] = condicao2(Melhor_producao_FIM2, Melhores_turnos_FIM2);
                     
                Prod_liq(i,:) = producao_liq_intervalos(Melhor_producao_FIM3);                     
                Media_individuos(i,:) = media_ind(Melhores_turnos_FIM3);
                
            end
            
           
            Intervalo_1 = Media_individuos(:,1);
            Intervalo_2 = Media_individuos(:,2);
            Intervalo_3 = Media_individuos(:,3);
            Intervalo_4 = Media_individuos(:,4);
            
            Prod_liq_1 = Prod_liq(:,1);
            Prod_liq_2 = Prod_liq(:,2);
            Prod_liq_3 = Prod_liq(:,3);
            Prod_liq_4 = Prod_liq(:,4);
            
            fprintf('    M1    M2    M3    M4    M5    M6    M7\n')
            disp(Melhores_turnos_FIM3);
            disp(Melhor_producao_FIM3);
            
            app.UITable.ColumnWidth = {75,'auto','auto','auto','auto','auto','auto','auto','auto'};
            app.UITable.RowName = 'numbered';
            Table = horzcat(Melhores_turnos_FIM3,Melhor_producao_FIM3);
            app.UITable.Data = Table;
            
            plot(app.UIAxes,Prod_liq_1,'-');
            hold(app.UIAxes);
            plot(app.UIAxes,Prod_liq_2,'-');
            hold(app.UIAxes);
            legend(app.UIAxes,'Prod Liq 1', 'Prod Liq 2');
            hold(app.UIAxes);
            
            plot(app.UIAxes2,Intervalo_1,'-');
            hold(app.UIAxes2);
            plot(app.UIAxes2,Intervalo_2,'-');
            legend(app.UIAxes2,'Intervalo 1', 'Intervalo 2');
            hold(app.UIAxes2);
            
            plot(app.UIAxes3,Intervalo_3,'-');
            hold(app.UIAxes3);
            plot(app.UIAxes3,Intervalo_4,'-');
            legend(app.UIAxes3,'Intervalo 3', 'Intervalo 4');
            hold(app.UIAxes3);
            
            plot(app.UIAxes4,Prod_liq_3,'-');
            hold(app.UIAxes4);
            plot(app.UIAxes4,Prod_liq_4,'-');
            hold(app.UIAxes4);
            legend(app.UIAxes4,'Prod Liq 3', 'Prod Liq 4');
            hold(app.UIAxes4);
           
          
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SCHEDULINGButton = uibutton(app.UIFigure, 'state');
            app.SCHEDULINGButton.ValueChangedFcn = createCallbackFcn(app, @SCHEDULINGButtonValueChanged, true);
            app.SCHEDULINGButton.Text = 'SCHEDULING';
            app.Tarefa04AlgoritmosGenticosLabel = uilabel(app.UIFigure);
            app.Tarefa04AlgoritmosGenticosLabel.Text = 'Tarefa 04 - Algoritmos Genéticos';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'M1'; 'M2'; 'M3'; 'M4'; 'M5'; 'M6'; 'M7'; 'Producao Liq'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' Prod Liq 1 e 2 x Gerações')
            xlabel(app.UIAxes, 'Geracoes')
            ylabel(app.UIAxes, 'Producao Liquida Intervalos 1 e 2')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, ' Indivíduos/ intervalo 1 e 2 x Gerações')
            xlabel(app.UIAxes2, 'Gerações')
            ylabel(app.UIAxes2, 'Individuos Intervalos 1 e 2')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Indivíduos/ intervalo 3 e 4 x Gerações')
            xlabel(app.UIAxes3, 'Gerações')
            ylabel(app.UIAxes3, 'Individuos intervalos 3 e 4')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Prod liq 3 e 4 x Gerações')
            xlabel(app.UIAxes4, 'Gerações')
            ylabel(app.UIAxes4, 'Produção Liquida Intervalos 3 e 4')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarefa4app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end