classdef Tarefa5app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ProblemadoCaixeiroViajanteLabel  matlab.ui.control.Label
        City1XEditFieldLabel           matlab.ui.control.Label
        City1XEditField                matlab.ui.control.NumericEditField
        City1YEditFieldLabel           matlab.ui.control.Label
        City1YEditField                matlab.ui.control.NumericEditField
        City2XEditFieldLabel           matlab.ui.control.Label
        City2XEditField                matlab.ui.control.NumericEditField
        City2YEditFieldLabel           matlab.ui.control.Label
        City2YEditField                matlab.ui.control.NumericEditField
        City3XEditFieldLabel           matlab.ui.control.Label
        City3XEditField                matlab.ui.control.NumericEditField
        City3YEditFieldLabel           matlab.ui.control.Label
        City3YEditField                matlab.ui.control.NumericEditField
        City4XEditFieldLabel           matlab.ui.control.Label
        City4XEditField                matlab.ui.control.NumericEditField
        City4YEditFieldLabel           matlab.ui.control.Label
        City4YEditField                matlab.ui.control.NumericEditField
        City5XEditFieldLabel           matlab.ui.control.Label
        City5XEditField                matlab.ui.control.NumericEditField
        City5YEditFieldLabel           matlab.ui.control.Label
        City5YEditField                matlab.ui.control.NumericEditField
        City6XEditFieldLabel           matlab.ui.control.Label
        City6XEditField                matlab.ui.control.NumericEditField
        City6YEditFieldLabel           matlab.ui.control.Label
        City6YEditField                matlab.ui.control.NumericEditField
        City7XEditFieldLabel           matlab.ui.control.Label
        City7XEditField                matlab.ui.control.NumericEditField
        City7YEditFieldLabel           matlab.ui.control.Label
        City7YEditField                matlab.ui.control.NumericEditField
        City8XEditFieldLabel           matlab.ui.control.Label
        City8XEditField                matlab.ui.control.NumericEditField
        City8YEditFieldLabel           matlab.ui.control.Label
        City8YEditField                matlab.ui.control.NumericEditField
        City9XEditFieldLabel           matlab.ui.control.Label
        City9XEditField                matlab.ui.control.NumericEditField
        City9YEditFieldLabel           matlab.ui.control.Label
        City9YEditField                matlab.ui.control.NumericEditField
        City10XEditFieldLabel          matlab.ui.control.Label
        City10XEditField               matlab.ui.control.NumericEditField
        City10YEditFieldLabel          matlab.ui.control.Label
        City10YEditField               matlab.ui.control.NumericEditField
        Coordenadas010Label            matlab.ui.control.Label
        PLOTButton                     matlab.ui.control.Button
        TRAJETOButton                  matlab.ui.control.Button
        QuantascidadesEditFieldLabel   matlab.ui.control.Label
        QuantascidadesEditField        matlab.ui.control.NumericEditField
        IndividuosEditFieldLabel       matlab.ui.control.Label
        IndividuosEditField            matlab.ui.control.NumericEditField
        MenorTrajetoriaEditFieldLabel  matlab.ui.control.Label
        MenorTrajetoriaEditField       matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TRAJETOButton
        function TRAJETOButtonPushed(app, event)
        
        Auxiliar = 0;
        Distancias = 0;
        
        individuos = app.IndividuosEditField.Value;
        
            for i = 1:app.QuantascidadesEditField.Value
                A(i) = i;
            end
            for i = 1:individuos
                B(i,:) = randperm(length(A));
            end
       
            %cria matriz pop inicial
            Geracao_inicial = B;
            disp(Geracao_inicial);
            
            % matriz armazena as posições X das cidades
            for i = 1:length(A)
                if(i==1)
                    Posicoes_X(i) = app.City1XEditField.Value;
                elseif(i==2)
                    Posicoes_X(i) = app.City2XEditField.Value;
                elseif(i==3)
                    Posicoes_X(i) = app.City3XEditField.Value;
                elseif(i==4)
                    Posicoes_X(i) = app.City4XEditField.Value;
                elseif(i==5)
                    Posicoes_X(i) = app.City5XEditField.Value;
                elseif(i==6)
                    Posicoes_X(i) = app.City6XEditField.Value;
                elseif(i==7)
                    Posicoes_X(i) = app.City7XEditField.Value;
                elseif(i==8)
                    Posicoes_X(i) = app.City8XEditField.Value;
                elseif(i==9)
                    Posicoes_X(i) = app.City9XEditField.Value;
                else
                    Posicoes_X(i) = app.City10XEditField.Value;
                end
            end
            
            disp(Posicoes_X);
            
            % % matriz armazena as posições Y das cidades
            for i = 1:length(A)
                if(i==1)
                    Posicoes_Y(i) = app.City1YEditField.Value;
                elseif(i==2)
                    Posicoes_Y(i) = app.City2YEditField.Value;
                elseif(i==3)
                    Posicoes_Y(i) = app.City3YEditField.Value;
                elseif(i==4)
                    Posicoes_Y(i) = app.City4YEditField.Value;
                elseif(i==5)
                    Posicoes_Y(i) = app.City5YEditField.Value;
                elseif(i==6)
                    Posicoes_Y(i) = app.City6YEditField.Value;
                elseif(i==7)
                    Posicoes_Y(i) = app.City7YEditField.Value;
                elseif(i==8)
                    Posicoes_Y(i) = app.City8YEditField.Value;
                elseif(i==9)
                    Posicoes_Y(i) = app.City9YEditField.Value;
                else
                    Posicoes_Y(i) = app.City10YEditField.Value;
                end
            end
            
            % % cálculo dos fitness dos indivíduos
            Distancias = trajetoria(Geracao_inicial, Posicoes_X, Posicoes_Y, A, individuos);
%             disp(Distancias);
            
%            %melhor_trajetoria
            Menor_trajetoria = min(Distancias);
            Melhor_trajetoria = find(Menor_trajetoria == Distancias);
            Selecionado = Geracao_inicial(Melhor_trajetoria,:);
            disp(Selecionado);
            
            % % utilizar elitismo para seleção dos pais
            Pais = elitismo_pcv(Distancias, Geracao_inicial,individuos);
            disp(Pais);
            
            % % cruzamento PMX
            Filhos_pmx = pmx (Pais, A, individuos);
            disp(Filhos_pmx);
            
            % % swap mutation
            Filhos_swap = swap(Filhos_pmx, A, individuos);
            disp(Filhos_swap);
            
            disp(' -------------------------------------------');
            
            %recomeça
            Geracao_inicial = Filhos_swap;
            
            for loop = 1:200
                
                % % cálculo dos fitness dos indivíduos
                Distancias = trajetoria(Geracao_inicial, Posicoes_X, Posicoes_Y, A, individuos);
                disp(Distancias);
                
                %melhor_trajetoria
                Menor_trajetoria = min(Distancias);
                Melhor_trajetoria = min(find(Menor_trajetoria == Distancias));
                Selecionado = Geracao_inicial(Melhor_trajetoria,:);
                
                % % utilizar elitismo para seleção dos pais
                Pais = elitismo_pcv(Distancias, Geracao_inicial,individuos);
                disp(Pais);
                
                % % cruzamento PMX
                Filhos_pmx = pmx (Pais, A, individuos);
                disp(Filhos_pmx);
                
                % % swap mutation
                Filhos_swap = swap(Filhos_pmx, A, individuos);
                disp(Filhos_swap);
                
                %recomeça
                Geracao_inicial = Filhos_swap;
                
            end
            
            
            %melhor_trajetoria
            Menor_trajetoria = min(Distancias);
            Melhor_trajetoria = min(find(Menor_trajetoria == Distancias));
            Selecionado = Geracao_inicial(Melhor_trajetoria,:);
            
            app.MenorTrajetoriaEditField.Value = Menor_trajetoria;
            disp('---------- FIM')
            disp(Selecionado(1,:));
            disp(Menor_trajetoria);
            
            Table = Selecionado(1,:);
            app.UITable.ColumnWidth = {'auto','auto','auto','auto','auto','auto','auto','auto'};
            app.UITable.RowName = 'numbered';
            app.UITable.Data = Table;
            
            Trajeto = Selecionado(1,:);
            
            %             %ligar os pontos
            for i = 1:length(A)
                Matrix_pontos(i,1) = (Posicoes_X(1,Trajeto(1,i)));
                Matrix_pontos(i,2) = (Posicoes_Y(1,Trajeto(1,i)));
            end
           
            final = length(A)+1;
            Matrix_pontos(final,1) = Matrix_pontos(1,1);
            Matrix_pontos(final,2) = Matrix_pontos(1,2);
            
            hold(app.UIAxes);
            

                plot(app.UIAxes,Matrix_pontos(:,1),Matrix_pontos(:,2),'-');


        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
    A = app.QuantascidadesEditField.Value;
            
            
            % matriz armazena as posições X das cidades
            for i = 1:A
                if(i==1)
                    Posicoes_X(i,1) = app.City1XEditField.Value;
                elseif(i==2)
                    Posicoes_X(i,1) = app.City2XEditField.Value;
                elseif(i==3)
                    Posicoes_X(i,1) = app.City3XEditField.Value;
                elseif(i==4)
                    Posicoes_X(i,1) = app.City4XEditField.Value;
                elseif(i==5)
                    Posicoes_X(i,1) = app.City5XEditField.Value;
                elseif(i==6)
                    Posicoes_X(i,1) = app.City6XEditField.Value;
                elseif(i==7)
                    Posicoes_X(i,1) = app.City7XEditField.Value;
                elseif(i==8)
                    Posicoes_X(i,1) = app.City8XEditField.Value;
                elseif(i==9)
                    Posicoes_X(i,1) = app.City9XEditField.Value;
                else
                    Posicoes_X(i,1) = app.City10XEditField.Value;
                end
            end
                        
            % % matriz armazena as posições Y das cidades
            for i = 1:A
                if(i==1)
                    Posicoes_Y(i,1) = app.City1YEditField.Value;
                elseif(i==2)
                    Posicoes_Y(i,1) = app.City2YEditField.Value;
                elseif(i==3)
                    Posicoes_Y(i,1) = app.City3YEditField.Value;
                elseif(i==4)
                    Posicoes_Y(i,1) = app.City4YEditField.Value;
                elseif(i==5)
                    Posicoes_Y(i,1) = app.City5YEditField.Value;
                elseif(i==6)
                    Posicoes_Y(i,1) = app.City6YEditField.Value;
                elseif(i==7)
                    Posicoes_Y(i,1) = app.City7YEditField.Value;
                elseif(i==8)
                    Posicoes_Y(i,1) = app.City8YEditField.Value;
                elseif(i==9)
                    Posicoes_Y(i,1) = app.City9YEditField.Value;
                else
                    Posicoes_Y(i,1) = app.City10YEditField.Value;
                end
            end
%             
             disp('X');
            disp(Posicoes_X);
            disp('Y');
            disp(Posicoes_Y);
            
            plot(app.UIAxes,Posicoes_X,Posicoes_Y,'+');
            hold(app.UIAxes);
            xlabel('X');
            ylabel('Y');
            hold(app.UIAxes);
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TRAJETO')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProblemadoCaixeiroViajanteLabel = uilabel(app.UIFigure);
            app.ProblemadoCaixeiroViajanteLabel.Text = 'Problema do Caixeiro Viajante';
            app.City1XEditFieldLabel = uilabel(app.UIFigure);
            app.City1XEditFieldLabel.Text = 'City 1 X:';
            app.City1XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City1YEditFieldLabel = uilabel(app.UIFigure);
            app.City1YEditFieldLabel.Text = 'City 1 Y:';
            app.City1YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City2XEditFieldLabel = uilabel(app.UIFigure);
            app.City2XEditFieldLabel.Text = 'City 2 X:';
            app.City2XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City2YEditFieldLabel = uilabel(app.UIFigure);
            app.City2YEditFieldLabel.Text = 'City 2 Y:';
            app.City2YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City3XEditFieldLabel = uilabel(app.UIFigure);
            app.City3XEditFieldLabel.Text = 'City 3 X:';
            app.City3XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City3YEditFieldLabel = uilabel(app.UIFigure);
            app.City3YEditFieldLabel.Text = 'City 3 Y:';
            app.City3YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City4XEditFieldLabel = uilabel(app.UIFigure);
            app.City4XEditFieldLabel.Text = 'City 4 X:';
            app.City4XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City4YEditFieldLabel = uilabel(app.UIFigure);
            app.City4YEditFieldLabel.Text = 'City 4 Y:';
            app.City4YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City5XEditFieldLabel = uilabel(app.UIFigure);
            app.City5XEditFieldLabel.Text = 'City 5 X:';
            app.City5XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City5YEditFieldLabel = uilabel(app.UIFigure);
            app.City5YEditFieldLabel.Text = 'City 5 Y:';
            app.City5YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City6XEditFieldLabel = uilabel(app.UIFigure);
            app.City6XEditFieldLabel.Text = 'City 6 X:';
            app.City6XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City6YEditFieldLabel = uilabel(app.UIFigure);
            app.City6YEditFieldLabel.Text = 'City 6 Y:';
            app.City6YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City7XEditFieldLabel = uilabel(app.UIFigure);
            app.City7XEditFieldLabel.Text = 'City 7 X:';
            app.City7XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City7YEditFieldLabel = uilabel(app.UIFigure);
            app.City7YEditFieldLabel.Text = 'City 7 Y:';
            app.City7YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City8XEditFieldLabel = uilabel(app.UIFigure);
            app.City8XEditFieldLabel.Text = 'City 8 X:';
            app.City8XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City8YEditFieldLabel = uilabel(app.UIFigure);
            app.City8YEditFieldLabel.Text = 'City 8 Y:';
            app.City8YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City9XEditFieldLabel = uilabel(app.UIFigure);
            app.City9XEditFieldLabel.Text = 'City 9 X:';
            app.City9XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City9YEditFieldLabel = uilabel(app.UIFigure);
            app.City9YEditFieldLabel.Text = 'City 9 Y:';
            app.City9YEditField = uieditfield(app.UIFigure, 'numeric');
            app.City10XEditFieldLabel = uilabel(app.UIFigure);
            app.City10XEditFieldLabel.Text = 'City 10 X:';
            app.City10XEditField = uieditfield(app.UIFigure, 'numeric');
            app.City10YEditFieldLabel = uilabel(app.UIFigure);
            app.City10YEditFieldLabel.Text = 'City 10 Y:';
            app.City10YEditField = uieditfield(app.UIFigure, 'numeric');
            app.Coordenadas010Label = uilabel(app.UIFigure);
            app.Coordenadas010Label.Text = 'Coordenadas - [0 10]';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.TRAJETOButton = uibutton(app.UIFigure, 'push');
            app.TRAJETOButton.ButtonPushedFcn = createCallbackFcn(app, @TRAJETOButtonPushed, true);
            app.TRAJETOButton.Text = 'TRAJETO';
            app.QuantascidadesEditFieldLabel = uilabel(app.UIFigure);
            app.QuantascidadesEditFieldLabel.Text = 'Quantas cidades';
            app.QuantascidadesEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndividuosEditFieldLabel = uilabel(app.UIFigure);
            app.IndividuosEditFieldLabel.Text = 'Individuos';
            app.IndividuosEditField = uieditfield(app.UIFigure, 'numeric');
            app.MenorTrajetoriaEditFieldLabel = uilabel(app.UIFigure);
            app.MenorTrajetoriaEditFieldLabel.Text = 'Menor Trajetoria';
            app.MenorTrajetoriaEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PONTO1'; 'PONTO2'; 'PONTO3'; 'PONTO4'; 'PONTO5'; 'PONTO6'; 'PONTO7'; 'PONTO8'; 'PONTO9'; 'PONTO10'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarefa5app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end