classdef Tarefa6app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IndividuosEditFieldLabel        matlab.ui.control.Label
        IndividuosEditField             matlab.ui.control.NumericEditField
        ProblemadoCavalodeXadrezLabel   matlab.ui.control.Label
        GeracoesEditFieldLabel          matlab.ui.control.Label
        GeracoesEditField               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        EliteEditFieldLabel             matlab.ui.control.Label
        EliteEditField                  matlab.ui.control.NumericEditField
        Grupo_TorneioEditFieldLabel     matlab.ui.control.Label
        Grupo_TorneioEditField          matlab.ui.control.NumericEditField
        PLAYButton                      matlab.ui.control.Button
        Melhor_pontuacaoEditFieldLabel  matlab.ui.control.Label
        Melhor_pontuacaoEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
        
            Casa = catalogo();
            
            Individuos = app.IndividuosEditField.Value;
            Geracoes = app.GeracoesEditField.Value;
           
            Elite = app.EliteEditField.Value;
            Grupo_Torneio = app.Grupo_TorneioEditField.Value;
            
            % criar um cromossomo de 64 posições - semelhante ao problema das cidades
            
            A = Casa(:,1);
            A = A.';
            
            %vetor aleatorio de posições iniciais
            % B(1,:) = randperm(length(A));
            
            for i = 1:Individuos
                
                B(1,:) = randperm(length(A));
                Pop_inicial(i,:) = B(:,:);
            
            end
            
            % disp(Pop_inicial);
            
            %fazer um for para o tanto de individuos necessários para os calculos
            Avaliacao = avaliacao(Pop_inicial, Individuos);
            
            % disp(Avaliacao);
            
            %avaliação - avaliar os individuos com as posicções aleatorias
            %aqueles individuos que tiverem posições subsequentes corretas colocar 1
            %para correto, fazer a multipicacao e somar os individuos que tiverem
            %maiores somas passarão adiante -> visando o melhor individuo com melhor
            %percurso
            
            Filhos_elitismo = elitismo(Pop_inicial, Individuos, Avaliacao, Elite, Grupo_Torneio);
            
            % disp(Filhos_elitismo);
            
            % agora realizar o crossover PMX
            Filhos_pmx = pmx(Filhos_elitismo, A, Individuos);
            
            % disp(Filhos_pmx);
            
            %realizar a mutação SWAP
            Filhos_swap = swap(Filhos_elitismo, Individuos);
            
            % disp(Filhos_swap);
            
            Media(1,1) = (sum(Avaliacao)/Individuos);
            
            Melhor_geracao(1,1) = max(Avaliacao);
            
            %Nova Geracao
            Pop_inicial2 = Filhos_swap;
             
            for loop = 2:Geracoes %alterar para 10000 gerações
            
                Avaliacao2 = avaliacao(Pop_inicial2, Individuos);
            
            %     disp(Avaliacao2);
                
                Filhos_elitismo = elitismo(Pop_inicial2, Individuos, Avaliacao2, Elite, Grupo_Torneio);
                
            %     disp(Filhos_elitismo);
                
                %agora realizar o crossover PMX
                Filhos_pmx = pmx(Filhos_elitismo, A ,Individuos);
                
                %realizar a mutação SWAP
                Filhos_swap = swap(Filhos_pmx, Individuos);
                
            %     disp(Filhos_swap);
                
                %Nova Geracao
                Pop_inicial2 = Filhos_swap;
                
                %Media dos fitness
                Media(1,loop) = (sum(Avaliacao2)/Individuos);
                
                %Melhor da geracao
                Melhor_geracao(1,loop) = max(Avaliacao2);
            
            end
            
            Melhor_pontuacao = max(Avaliacao2);
            
            Melhor_individuo = min(find(Melhor_pontuacao == Avaliacao2));
            
            Melhor_trajetoria = Pop_inicial2(Melhor_individuo, :);
            
            disp(Melhor_pontuacao);
            disp(Melhor_trajetoria);

            %plotar trajetória 
            %pegar melhor trajetoria, no catalogo pegar coordenadas x e y
            
            for i = 1:64
                
                Posicao_X(1,i) = Casa(Melhor_trajetoria(1,i),2);
                Posicao_Y(1,i) = Casa(Melhor_trajetoria(1,i),3);
                
            end
            
            app.Melhor_pontuacaoEditField.Value = Melhor_pontuacao;          
            
            plot(app.UIAxes,Media,'*');
            hold(app.UIAxes);
            plot(app.UIAxes, Melhor_geracao,'-');
            xlabel('Geracoes');
            ylabel('Media Fitness');
            legend(app.UIAxes,'Media Geracao', 'Melhor Individuo');
            app.UIAxes.YLim = [0 64];
            app.UIAxes.XLim = [ 0 10000];
            hold(app.UIAxes);
            
            disp(Media);
                              
            for i = 1:64
                
                Posicao_X(1,i) = Casa(Melhor_trajetoria(1,i),2);
                Posicao_Y(1,i) = Casa(Melhor_trajetoria(1,i),3);
                
            end
        
            plot(app.UIAxes2,Posicao_X,Posicao_Y,'-');
            hold(app.UIAxes2);
            plot(app.UIAxes2,Posicao_X,Posicao_Y,'.',"MarkerSize",12);            
            xlabel('Tabuleiro eixo X');
            ylabel('Tabuleiro eixo Y');
            hold(app.UIAxes2);
            app.UIAxes2.XLim = [0 9];
            app.UIAxes2.YLim = [0 9];
            legend(app.UIAxes2,'Movimento Cavalo');
            app.UIAxes2.GridAlphaMode = 'auto';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.IndividuosEditFieldLabel = uilabel(app.UIFigure);
            app.IndividuosEditFieldLabel.Text = 'Individuos';
            app.IndividuosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProblemadoCavalodeXadrezLabel = uilabel(app.UIFigure);
            app.ProblemadoCavalodeXadrezLabel.Text = 'Problema do Cavalo de Xadrez';
            app.GeracoesEditFieldLabel = uilabel(app.UIFigure);
            app.GeracoesEditFieldLabel.Text = 'Geracoes';
            app.GeracoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Média e Fitness')
            xlabel(app.UIAxes, 'Geracoes')
            ylabel(app.UIAxes, 'Media Fitness e Melhor Individuo')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trajetória')
            xlabel(app.UIAxes2, 'Tabuleiro eixo X')
            ylabel(app.UIAxes2, 'Tabuleiro eixo Y')
            app.EliteEditFieldLabel = uilabel(app.UIFigure);
            app.EliteEditFieldLabel.Text = 'Elite';
            app.EliteEditField = uieditfield(app.UIFigure, 'numeric');
            app.Grupo_TorneioEditFieldLabel = uilabel(app.UIFigure);
            app.Grupo_TorneioEditFieldLabel.Text = 'Grupo_Torneio';
            app.Grupo_TorneioEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.Melhor_pontuacaoEditFieldLabel = uilabel(app.UIFigure);
            app.Melhor_pontuacaoEditFieldLabel.Text = 'Melhor_pontuacao';
            app.Melhor_pontuacaoEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarefa6app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end