classdef Tarefa7app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        MelhorPontoEncontradoLabel     matlab.ui.control.Label
        IndividuosEditFieldLabel       matlab.ui.control.Label
        IndividuosEditField            matlab.ui.control.NumericEditField
        ParametrosEditFieldLabel       matlab.ui.control.Label
        ParametrosEditField            matlab.ui.control.NumericEditField
        EliteEditFieldLabel            matlab.ui.control.Label
        EliteEditField                 matlab.ui.control.NumericEditField
        Grupo_torneioEditFieldLabel    matlab.ui.control.Label
        Grupo_torneioEditField         matlab.ui.control.NumericEditField
        ProbCruzamentosEditFieldLabel  matlab.ui.control.Label
        ProbCruzamentosEditField       matlab.ui.control.NumericEditField
        MutacoesEditFieldLabel         matlab.ui.control.Label
        MutacoesEditField              matlab.ui.control.NumericEditField
        MinimizaodaFunodeRastriginLabel  matlab.ui.control.Label
        FitnessEditFieldLabel          matlab.ui.control.Label
        FitnessEditField               matlab.ui.control.NumericEditField
        GeraesEditFieldLabel           matlab.ui.control.Label
        GeraesEditField                matlab.ui.control.NumericEditField
        PLAYButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            Parametros = app.ParametrosEditField;
            Individuos = app.IndividuosEditField;
            Bits = Parametros;
            Elite = app.EliteEditField;
            Grupo_torneio = app.Grupo_torneioEditField;
            Pc = app.ProbCruzamentosEditField;
            Qde_mutacoes = app.MutacoesEditField;
            
            %geração inicial variação do x entre -5.12 e 5.12 ARRUMAR
            [X,Y] = meshgrid(-5.12:.1:5.12);
            
            %plotando a função
            Funcao = 20 + (X.*X- 10*cos(2*pi.*X)) +(Y.*Y - 10*cos(2*pi.*Y));
            
            mesh(app.UIAxes, X,Y,Funcao);
            hold on
            
            
            %Geracao Inicial
            Geracao_inicial = geracao_inicial (Individuos, Bits);
            
            % disp(Geracao_inicial);
            
            %calcula o fitness
            Fitness = calcula_fitness (Geracao_inicial, Individuos, Bits);
            
            % disp(Fitness);
            
            %coletam os valores minimos
            [Melhores_individuos, Melhores_encontrados, Melhores_Fitness] = coleta_minimo(Geracao_inicial,Fitness, Individuos,Bits, Elite, Grupo_torneio);
            
            plot3(app.UIAxes1, Melhores_encontrados(:,1),Melhores_encontrados(:,2),Melhores_Fitness,'s');
            plot3(app.UIAxes2, Melhores_encontrados(:,1),Melhores_encontrados(:,2),Melhores_Fitness,'o');
            grid on
            Xlim(app.UIAxes2,[-5.12 5.12]);
            Ylim(app.UIAxes2,[-5.12 5.12]);
            Zlim(app.UIAxes2,[0 100]);
            
            drawnow
            hold on
            
            %Cruzamento Radcliff
            Pais_cruzamento = cruzamento_Radcliff(Melhores_individuos, Individuos, Bits, Pc);
            
            %             disp('Pais_cruzamento');
            %             disp(Pais_cruzamento);
            
            %cruzamento Wright
            Pais_cruzamento2 = cruzamento_Wright(Pais_cruzamento, Individuos, Bits, Pc);
            
            %             disp('Pais_cruzamento2');
            %             disp(Pais_cruzamento2);
            
            %taxa de mutação pm
            Pais_mutacao = mutacao(Pais_cruzamento2, Individuos, Bits, Qde_mutacoes);
            
            %Nova Geração
            Nova_geracao = Pais_mutacao;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Função de Rastrigin')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Melhores Pontos')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Média Fitness x Gerações')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.MelhorPontoEncontradoLabel = uilabel(app.UIFigure);
            app.MelhorPontoEncontradoLabel.Text = 'Melhor Ponto Encontrado';
            app.IndividuosEditFieldLabel = uilabel(app.UIFigure);
            app.IndividuosEditFieldLabel.Text = 'Individuos';
            app.IndividuosEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndividuosEditField.Value = 10;
            app.ParametrosEditFieldLabel = uilabel(app.UIFigure);
            app.ParametrosEditFieldLabel.Text = 'Parametros';
            app.ParametrosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParametrosEditField.Value = 2;
            app.EliteEditFieldLabel = uilabel(app.UIFigure);
            app.EliteEditFieldLabel.Text = 'Elite';
            app.EliteEditField = uieditfield(app.UIFigure, 'numeric');
            app.EliteEditField.Value = 5;
            app.Grupo_torneioEditFieldLabel = uilabel(app.UIFigure);
            app.Grupo_torneioEditFieldLabel.Text = 'Grupo_torneio';
            app.Grupo_torneioEditField = uieditfield(app.UIFigure, 'numeric');
            app.Grupo_torneioEditField.Value = 3;
            app.ProbCruzamentosEditFieldLabel = uilabel(app.UIFigure);
            app.ProbCruzamentosEditFieldLabel.Text = 'Prob. Cruzamentos';
            app.ProbCruzamentosEditField = uieditfield(app.UIFigure, 'numeric');
            app.MutacoesEditFieldLabel = uilabel(app.UIFigure);
            app.MutacoesEditFieldLabel.Text = 'Mutacoes';
            app.MutacoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimizaodaFunodeRastriginLabel = uilabel(app.UIFigure);
            app.MinimizaodaFunodeRastriginLabel.Text = 'Minimização da Função de Rastrigin ';
            app.FitnessEditFieldLabel = uilabel(app.UIFigure);
            app.FitnessEditFieldLabel.Text = 'Fitness';
            app.FitnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeraesEditFieldLabel = uilabel(app.UIFigure);
            app.GeraesEditFieldLabel.Text = 'Gerações';
            app.GeraesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarefa7app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end