classdef Tarefa7app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        PLAYButton                   matlab.ui.control.Button
        IndividuosEditFieldLabel     matlab.ui.control.Label
        IndividuosEditField          matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        GeracoesEditFieldLabel       matlab.ui.control.Label
        GeracoesEditField            matlab.ui.control.NumericEditField
        ProbCruzEditFieldLabel       matlab.ui.control.Label
        ProbCruzEditField            matlab.ui.control.NumericEditField
        MutacoesEditFieldLabel       matlab.ui.control.Label
        MutacoesEditField            matlab.ui.control.NumericEditField
        EliteEditFieldLabel          matlab.ui.control.Label
        EliteEditField               matlab.ui.control.NumericEditField
        Grupo_torneioEditFieldLabel  matlab.ui.control.Label
        Grupo_torneioEditField       matlab.ui.control.NumericEditField
        MinimizaodaFunodeRastriginLabel  matlab.ui.control.Label
        MelhorPontoEncontradoLabel   matlab.ui.control.Label
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        FitnessEditFieldLabel        matlab.ui.control.Label
        FitnessEditField             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
        
            
            Individuos = app.IndividuosEditField.Value;
            Bits = app.NEditField.Value;
            Elite = app.EliteEditField.Value;
            Grupo_torneio = app.Grupo_torneioEditField.Value;
            Pc = app.ProbCruzEditField.Value;
            Qde_mutacoes = app.MutacoesEditField.Value;
            Geracoes = app.GeracoesEditField.Value;
            
            %geração inicial variação do x entre -5.12 e 5.12 
            [X,Y] = meshgrid(-5.12:.1:5.12);
            
            %plotando a função
            Funcao = 20 + (X.*X- 10*cos(2*pi.*X)) +(Y.*Y - 10*cos(2*pi.*Y));
            
            mesh(app.UIAxes, X,Y,Funcao);
            hold(app.UIAxes);
            
            %Geracao Inicial
            Geracao_inicial = geracao_inicial (Individuos, Bits);
            
            % disp(Geracao_inicial);
            
            %calcula o fitness
            Fitness = calcula_fitness (Geracao_inicial, Individuos, Bits);
            
           %Media da Geracao
            Media(1,1) = sum(Fitness)/Individuos;
            
            plot(app.UIAxes3,Media);
            
            %coletam os valores minimos
            [Melhores_individuos, Melhores_encontrados, Melhores_Fitness] = coleta_minimo(Geracao_inicial,Fitness, Individuos,Bits, Elite, Grupo_torneio);
            
%             plot3(app.UIAxes, Melhores_encontrados(:,1),Melhores_encontrados(:,2),Melhores_Fitness,'s');
            plot3(app.UIAxes2, Melhores_encontrados(:,1),Melhores_encontrados(:,2),Melhores_Fitness,'o');
            grid on
            app.UIAxes2.XLim =[-5.12 5.12];
            app.UIAxes2.YLim =[-5.12 5.12];
            app.UIAxes2.ZLim =[0 100];
                     
            drawnow
            hold on
            
            %Cruzamento Radcliff
            Pais_cruzamento = cruzamento_Radcliff(Melhores_individuos, Individuos, Bits, Pc);
            
            
            %cruzamento Wright
            Pais_cruzamento2 = cruzamento_Wright(Pais_cruzamento, Individuos, Bits, Pc);
            
            
            %taxa de mutação pm
            Pais_mutacao = mutacao(Pais_cruzamento2, Individuos, Bits, Qde_mutacoes);
            
            %Nova Geração
            Nova_geracao = Pais_mutacao;
            
            for loop = 2:Geracoes
                %calcula o fitness
                Fitness = calcula_fitness (Nova_geracao, Individuos, Bits);
                
                %Media da Geracao
                Media(1,loop) = sum(Fitness)/Individuos;
                
                plot(app.UIAxes3,Media);
                app.UIAxes3.XLim =[0  2000];
                app.UIAxes3.YLim =[0 100];
                
                %coletam os valores minimos
                [Melhores_individuos, Melhores_encontrados, Melhores_Fitness] = coleta_minimo(Geracao_inicial,Fitness, Individuos,Bits, Elite, Grupo_torneio);
                
%                 plot3(app.UIAxes, Melhores_encontrados(:,1),Melhores_encontrados(:,2),Melhores_Fitness,'s');
                plot3(app.UIAxes2, Melhores_encontrados(:,1),Melhores_encontrados(:,2),Melhores_Fitness,'o');
                grid on
                app.UIAxes2.XLim =[-5.12 5.12];
                app.UIAxes2.YLim =[-5.12 5.12];
                app.UIAxes2.ZLim =[0 100];
                app.UIAxes2.GridLineStyle;
                drawnow
                hold on
                
                %Cruzamento Radcliff
                Pais_cruzamento = cruzamento_Radcliff(Melhores_individuos, Individuos, Bits, Pc);
                
                
                %cruzamento Wright
                Pais_cruzamento2 = cruzamento_Wright(Pais_cruzamento, Individuos, Bits, Pc);
                
                
                %taxa de mutação pm
                Pais_mutacao = mutacao(Pais_cruzamento2, Individuos, Bits, Qde_mutacoes);
                
                %Nova Geração
                Nova_geracao = Pais_mutacao;
            end
            
            Menor = min(Melhores_Fitness);
            Index = find(Menor == Melhores_Fitness);
            Menor_X = Melhores_encontrados(Index,1);
            Menor_Y = Melhores_encontrados(Index,2);
            app.XEditField.Value = Menor_X;
            app.YEditField.Value = Menor_Y;
            app.FitnessEditField.Value = Menor;
        end

        % Value changed function: GeracoesEditField
        function GeracoesEditFieldValueChanged(app, event)
            value = app.GeracoesEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Função de Rastrigin ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Melhores Pontos Encontrados')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Media Fitness x Geracoes')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.IndividuosEditFieldLabel = uilabel(app.UIFigure);
            app.IndividuosEditFieldLabel.Text = 'Individuos';
            app.IndividuosEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndividuosEditField.Value = 10;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 2;
            app.GeracoesEditFieldLabel = uilabel(app.UIFigure);
            app.GeracoesEditFieldLabel.Text = 'Geracoes';
            app.GeracoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeracoesEditField.ValueChangedFcn = createCallbackFcn(app, @GeracoesEditFieldValueChanged, true);
            app.GeracoesEditField.Value = 100;
            app.ProbCruzEditFieldLabel = uilabel(app.UIFigure);
            app.ProbCruzEditFieldLabel.Text = 'Prob Cruz.';
            app.ProbCruzEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbCruzEditField.Value = 0.6;
            app.MutacoesEditFieldLabel = uilabel(app.UIFigure);
            app.MutacoesEditFieldLabel.Text = 'Mutacoes';
            app.MutacoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MutacoesEditField.Value = 100;
            app.EliteEditFieldLabel = uilabel(app.UIFigure);
            app.EliteEditFieldLabel.Text = 'Elite';
            app.EliteEditField = uieditfield(app.UIFigure, 'numeric');
            app.EliteEditField.Value = 5;
            app.Grupo_torneioEditFieldLabel = uilabel(app.UIFigure);
            app.Grupo_torneioEditFieldLabel.Text = 'Grupo_torneio';
            app.Grupo_torneioEditField = uieditfield(app.UIFigure, 'numeric');
            app.Grupo_torneioEditField.Value = 3;
            app.MinimizaodaFunodeRastriginLabel = uilabel(app.UIFigure);
            app.MinimizaodaFunodeRastriginLabel.Text = 'Minimização da Função de Rastrigin';
            app.MelhorPontoEncontradoLabel = uilabel(app.UIFigure);
            app.MelhorPontoEncontradoLabel.Text = 'Melhor Ponto Encontrado';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.FitnessEditFieldLabel = uilabel(app.UIFigure);
            app.FitnessEditFieldLabel.Text = 'Fitness';
            app.FitnessEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarefa7app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end