classdef Tarefa8app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Tarefa8TratamentodeRestrioporPenalidadesLabel  matlab.ui.control.Label
        IndividuosEditFieldLabel     matlab.ui.control.Label
        IndividuosEditField          matlab.ui.control.NumericEditField
        GeracoesEditFieldLabel       matlab.ui.control.Label
        GeracoesEditField            matlab.ui.control.NumericEditField
        EliteEditFieldLabel          matlab.ui.control.Label
        EliteEditField               matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        UITable                      matlab.ui.control.Table
        PLAYButton                   matlab.ui.control.Button
        FitnessEditFieldLabel        matlab.ui.control.Label
        FitnessEditField             matlab.ui.control.NumericEditField
        Grupo_torneioEditFieldLabel  matlab.ui.control.Label
        Grupo_torneioEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            Individuos = app.IndividuosEditField.Value;
            Geracoes = app.GeracoesEditField.Value;
            Elite = app.EliteEditField.Value;
            Grupo_torneio = app.Grupo_torneioEditField.Value;
            
            Geracao_inicial = geracao_inicial(Individuos);
            disp(Geracao_inicial);
            %% Cálculo Fitness e Penalidades
            
            [Geracao, Penalidades] = calculo_fitness(Geracao_inicial, Individuos);
            
            disp(Penalidades);
            
            % Media Geracao
            Media_fitness(1,1) = sum(Penalidades)/Individuos;
            Ordenar_fitness = sort(Penalidades);
            Melhor_fitness(1,1) = Ordenar_fitness(1,1);
            disp(Melhor_fitness);

            
            %% Elitismo
            
            [Geracao_pais] = elitismo2(Geracao_inicial, Penalidades, Individuos, Elite, Grupo_torneio);
            
            disp(Geracao_pais);
            
            %% Crossover
            
            [Geracao_pais_cross] = crossover(Geracao_pais, Individuos);
            
            disp(Geracao_pais_cross);
            
            %% Mutação
            
            [Pais_mutacao] = mutacao(Geracao_pais_cross, Individuos);
            
            disp(Pais_mutacao);
            
            %% Nova Geração
            
            Nova_Geracao = Pais_mutacao;
            disp(Nova_Geracao);
            
            %% LOOP
            
            for loop = 2:Geracoes
                
                [Geracao, Penalidades] = calculo_fitness(Nova_Geracao, Individuos);
                
                disp(Penalidades);
                
                % Media Geracao
                Media_fitness(1,loop) = sum(Penalidades)/Individuos;
                Ordenar_fitness = sort(Penalidades);
                Melhor_fitness(1,loop) = Ordenar_fitness(1,1);
                
                disp(Melhor_fitness);
                
                %% Elitismo
                
                [Geracao_pais] = elitismo2(Nova_Geracao, Penalidades, Individuos, Elite, Grupo_torneio);
                
                disp(Geracao_pais);
                
                %% Crossover
                
                [Geracao_pais_cross] = crossover(Geracao_pais, Individuos);
                
                disp(Geracao_pais_cross);
                
                %% Mutação
                
                [Pais_mutacao] = mutacao(Geracao_pais_cross, Individuos);
                
                disp(Pais_mutacao);
                
                %% Nova Geração
                
                Nova_Geracao = Pais_mutacao;
                disp(Nova_Geracao);
                
            end
            
            Fitness = sort(Penalidades);
            Fitness = Fitness(1,1);
            disp(Fitness);
            Melhor_index = find(Fitness == Penalidades);
            Melhor_sequencia(:,:) = Nova_Geracao(min(Melhor_index),:);
            disp(Melhor_sequencia);
            app.FitnessEditField.Value = Fitness;
            
            %% gráfico e tabela
            app.UITable.ColumnWidth = {'auto','auto','auto','auto','auto','auto','auto','auto','auto'};
            app.UITable.RowName = 'Melhores Valores X';
            Table = horzcat(Melhor_sequencia);
            app.UITable.Data = Table;
            
            plot(app.UIAxes,Melhor_fitness,'-');
            hold(app.UIAxes);
            plot(app.UIAxes,Media_fitness,'-');
            legend(app.UIAxes, 'Melhor Fitness', 'Media Fitness');
            xlabel('Geracoes');
            ylabel('Valor');
            hold(app.UIAxes);
            app.UIAxes.XLim =[0 2000];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Tarefa8TratamentodeRestrioporPenalidadesLabel = uilabel(app.UIFigure);
            app.Tarefa8TratamentodeRestrioporPenalidadesLabel.Text = 'Tarefa 8 - Tratamento de Restrição por Penalidades';
            app.IndividuosEditFieldLabel = uilabel(app.UIFigure);
            app.IndividuosEditFieldLabel.Text = 'Individuos';
            app.IndividuosEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeracoesEditFieldLabel = uilabel(app.UIFigure);
            app.GeracoesEditFieldLabel.Text = 'Geracoes';
            app.GeracoesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EliteEditFieldLabel = uilabel(app.UIFigure);
            app.EliteEditFieldLabel.Text = 'Elite';
            app.EliteEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Média Fitness e Melhor Fitness')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x1'; 'x2'; 'x3'; 'x4'; 'x5'; 'x6'; 'x7'; 'x8'; 'x9'; 'x10'; 'x11'; 'x12'; 'x13'};
            app.UITable.RowName = {};
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.FitnessEditFieldLabel = uilabel(app.UIFigure);
            app.FitnessEditFieldLabel.Text = 'Fitness';
            app.FitnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.Grupo_torneioEditFieldLabel = uilabel(app.UIFigure);
            app.Grupo_torneioEditFieldLabel.Text = 'Grupo_torneio';
            app.Grupo_torneioEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tarefa8app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end